classdef Covid19 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CargadeDatosTab                 matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        RecopilacindeDatosButton        matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        COVID19porpaisTab               matlab.ui.container.Tab
        DefinirPaisButton               matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        UIAxes2                         matlab.ui.control.UIAxes
        NotaEstosdatossonlosreportadospordaLabel  matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        CasosacumuladosdelCOVID19Label  matlab.ui.control.Label
        DatePicker                      matlab.ui.control.DatePicker
        SeleccionelafechadeInicioLabel  matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        AjustedelaCurvadeCOVID19Tab     matlab.ui.container.Tab
        DropDown                        matlab.ui.control.DropDown
        SeleccioneelgradodelpolinomioLabel  matlab.ui.control.Label
        Lamp3                           matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Switch_2                        matlab.ui.control.Switch
        AjusteExponencialLabel          matlab.ui.control.Label
        AjustePolinomialLabel           matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        DatePicker_2                    matlab.ui.control.DatePicker
        DatePicker2                     matlab.ui.control.DatePicker
        DefInalafechainicialLabel       matlab.ui.control.Label
        DefinalafechafinalLabel         matlab.ui.control.Label
        GraficarButton                  matlab.ui.control.Button
        ComparacionesentrePasesTab      matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        Pas1Tab                         matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UITable3                        matlab.ui.control.Table
        PasActualLabel_2                matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ActualizarPas1Button            matlab.ui.control.Button
        Pas2Tab                         matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        UITable4                        matlab.ui.control.Table
        PasActualLabel                  matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        ActualizarPais2Button           matlab.ui.control.Button
        ComparacinTab                   matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        SeleccioneunaopcinDropDownLabel  matlab.ui.control.Label
        SeleccioneunaopcinDropDown      matlab.ui.control.DropDown
        Image                           matlab.ui.control.Image
    end

    % Carlos Tapia MultiON Consulting 2020 %
    properties (Access = public)
       %
       dataP=0;
       myctry='Mexico';
       Pais1=' ';
       Pais2=' ';
       dataP1= 0;
       dataP2= 0;
       %variables para realizaqr el ajuste polinomial
       grado=1;%grado del polinomio
       ajuste=0;%tipo de ajuste
       date_inicial=datetime(2019,12,31);%fecha inicial
       date_final=datetime('today');%fecha final
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecopilacindeDatosButton
        function RecopilacindeDatosButtonPushed(app, event)
            url = 'https://www.ecdc.europa.eu/sites/default/files/documents/COVID-19-geographic-disbtribution-worldwide.xlsx';
            data = webread(url);
            app.UITable.Data=data;
            app.UITable.ColumnName=data.Properties.VariableNames;
            
        end

        % Button pushed function: DefinirPaisButton
        function DefinirPaisButtonPushed(app, event)
            data=app.UITable.Data;
            %Eliminamos la información que no nos será de utilidad
            data = data(:,{'dateRep','cases','countriesAndTerritories'});
            Paises = data.countriesAndTerritories;
            data.countriesAndTerritories = categorical(data.countriesAndTerritories);
            paises = categories(data.countriesAndTerritories);
            idx = listdlg('PromptString','Elija un país:','SelectionMode','single','ListString',paises);
            myctry = string(paises{idx}); 
            app.myctry=myctry;
            app.Pais1=myctry;
            app.Label_2.Text=app.Pais1;
            dataP = data((data.countriesAndTerritories == myctry),:);
            sum (data.countriesAndTerritories == myctry);
            dataP = sortrows(dataP,'dateRep') ;
            app.UITable2.Data=dataP;
            app.dataP1= dataP(:,[1:end-1]);
            app.UITable3.Data=dataP(:,[1:end-1]);
            %Creamos la grafica
             bar(app.UIAxes2,dataP.dateRep,dataP.cases)
             text = strcat('Casos por día en:',{' '}, myctry) ;
             legend(app.UIAxes2,text,'Location',"northwest")
             xlabel(app.UIAxes2,'Fecha')
             ylabel(app.UIAxes2,'Cantidad de casos')
             axis(app.UIAxes2,'tight')
             xtickangle(app.UIAxes2,90)
             grid(app.UIAxes2,"on")
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
             dataP=app.UITable2.Data;
             Totalcasos = cumsum(dataP.cases);
             text = strcat('Casos por día en:',{' '}, app.myctry) ;
             xlabel(app.UIAxes2,'fecha')
             ylabel(app.UIAxes2,'Cantidad de casos')
            if value=='1'
                app.Lamp.Color='green';% Cambia el color de la lampara a verde
                app.Lamp.Enable='on'; % Habilita la lampara
                bar(app.UIAxes2,dataP.dateRep,dataP.cases)
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,dataP.dateRep,Totalcasos,'*:r',"LineWidth",1);%plot1
                textT = ['Casos Totales: ' num2str(Totalcasos(end))];
                var = {text,textT};
                legend(app.UIAxes2,var,'Location',"northwest")
                xlabel(app.UIAxes2,'Fecha')
                ylabel(app.UIAxes2,'Cantidad de casos')
                axis(app.UIAxes2,'tight')
                xtickangle(app.UIAxes2,90)
                xticks(app.UIAxes2,dataP.dateRep)
                grid(app.UIAxes2,"on")
                 hold(app.UIAxes2,"off")
        else
             app.Lamp.Color='red';% Cambia el color de la lampara a rojo
             app.Lamp.Enable='off';%Deshabilita la lampara
             bar(app.UIAxes2,dataP.dateRep,dataP.cases)
              text = strcat('Casos por día en:',{' '}, app.myctry) ;
              legend(app.UIAxes2,text,'Location',"northwest")
              xlabel(app.UIAxes2,'fecha')
              ylabel(app.UIAxes2,'Cantidad de casos')
              axis(app.UIAxes2,'tight')
              xtickangle(app.UIAxes2,90)
              grid(app.UIAxes2,"on")

            end
            
        end

        % Button pushed function: ActualizarPas1Button
        function ActualizarPas1ButtonPushed(app, event)
            data=app.UITable.Data;
            %Eliminamos la información que no nos será de utilidad
            data = data(:,{'dateRep','cases','countriesAndTerritories'});
            Paises = data.countriesAndTerritories;
            data.countriesAndTerritories = categorical(data.countriesAndTerritories);
            paises = categories(data.countriesAndTerritories);
            idx = listdlg('PromptString','Elija un país:','SelectionMode','single','ListString',paises);
            myctry = string(paises{idx}); 
            app.Pais1=myctry;
            app.Label_2.Text=app.Pais1;
            dataP = data((data.countriesAndTerritories == myctry),:);
            sum (data.countriesAndTerritories == myctry);
            dataP = sortrows(dataP,'dateRep');
            %Eliminamos los días donde aún no exitían casos de COVID 19
            dataP = dataP((dataP.cases~=0),:);         
            app.dataP1= dataP(:,[1:end-1]);
            app.UITable3.Data=dataP(:,[1:end-1]);
            %Creamos la grafica
             plot(app.UIAxes,dataP.dateRep,dataP.cases,'o--')
             title(app.UIAxes,'Casos Covid-19')
             text = strcat('Casos por día en:',{' '}, myctry) ;
             legend(app.UIAxes,text,'Location',"northwest")
             xlabel(app.UIAxes,'Fecha')
             ylabel(app.UIAxes,'Cantidad de casos')
             axis(app.UIAxes,'tight')
             grid(app.UIAxes,"on")
        end

        % Button pushed function: ActualizarPais2Button
        function ActualizarPais2ButtonPushed(app, event)
            data=app.UITable.Data;
            %Eliminamos la información que no nos será de utilidad
            data = data(:,{'dateRep','cases','deaths','countriesAndTerritories'});
            Paises = data.countriesAndTerritories;
            data.countriesAndTerritories = categorical(data.countriesAndTerritories);
            paises = categories(data.countriesAndTerritories);
            idx = listdlg('PromptString','Elija un país:','SelectionMode','single','ListString',paises);
            myctry = string(paises{idx}); 
            app.Pais2=myctry;
            app.Label3.Text=app.Pais2;
            dataP = data((data.countriesAndTerritories == myctry),:);
            sum (data.countriesAndTerritories == myctry);
            dataP = sortrows(dataP,'dateRep');
            %Eliminamos los días donde aún no exitían casos de COVID 19
            dataP = dataP((dataP.cases~=0),:);         
            app.dataP2= dataP(:,[1:end-1]);
            app.UITable4.Data=dataP(:,[1:end-1]);
            %Creamos la grafica
             plot(app.UIAxes4,dataP.dateRep,dataP.cases,'o--')
             title(app.UIAxes4,'Casos Covid-19')
             text = strcat('Casos por día en:',{' '}, myctry) ;
             legend(app.UIAxes4,text,'Location',"northwest")
             xlabel(app.UIAxes4,'Fecha')
             ylabel(app.UIAxes4,'Cantidad de casos')
             axis(app.UIAxes4,'tight')
             grid(app.UIAxes4,"on")
        end

        % Value changed function: SeleccioneunaopcinDropDown
        function SeleccioneunaopcinDropDownValueChanged(app, event)
            value = app.SeleccioneunaopcinDropDown.Value;
            if value == '1' %Casos por día
                plot(app.UIAxes5,app.dataP1.dateRep,app.dataP1.cases,'o--')
                hold (app.UIAxes5,"on")
                plot(app.UIAxes5,app.dataP2.dateRep,app.dataP2.cases,'<-r')
                text = strcat('Casos en:',{' '}, app.Pais1);
                text2 = strcat('Casos en:',{' '}, app.Pais2);
                legend(app.UIAxes5,text,text2,'Location',"northwest")
                xlabel(app.UIAxes5,'fecha')
                ylabel(app.UIAxes5,'Cantidad de casos')
                title(app.UIAxes5,'Casos Covid-19')
                axis(app.UIAxes5,'tight')
                grid(app.UIAxes5,"on")
                hold (app.UIAxes5,"off")
            else % Curva de crecimiento
                plot(app.UIAxes5,app.dataP1.dateRep,cumsum(app.dataP1.cases),'o--')
                hold (app.UIAxes5,"on")
                plot(app.UIAxes5,app.dataP2.dateRep,cumsum(app.dataP2.cases),'<-r')
                text = strcat('Curva de crecimiento en:',{' '}, app.Pais1);
                text2 = strcat('Curva de crecimiento en:',{' '}, app.Pais2);
                legend(app.UIAxes5,text,text2,'Location',"northwest")
                xlabel(app.UIAxes5,'fecha')
                ylabel(app.UIAxes5,'Cantidad de casos')
                title(app.UIAxes5,'Casos Covid-19')
                axis(app.UIAxes5,'tight')
                grid(app.UIAxes5,"on")
                hold (app.UIAxes5,"off")

            end
            
            
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            date = app.DatePicker.Value;
            dataP=app.dataP1;
            dataP = dataP(dataP.dateRep >= date,:);
            app.UITable2.Data=dataP;
%           %Creamos la grafica
             bar(app.UIAxes2,dataP.dateRep,dataP.cases)
             text = strcat('Casos por día en:',{' '}, app.Pais1) ;
             legend(app.UIAxes2,text,'Location',"northwest")
             xlabel(app.UIAxes2,'Fecha')
             ylabel(app.UIAxes2,'Cantidad de casos')
             axis(app.UIAxes2,'tight')
             grid(app.UIAxes2,"on")
            
        end

        % Callback function
        function SliderValueChanged(app, event)
            value = app.Slider.Value
            if value=='  '
                a=1;
            else
                a=2;
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            app.ajuste=value;
         if value=='1'% Ajuste Exponencial
                %app.Lamp2.Color='green';% Cambia el color de la lampara a verde
                app.Lamp2.Enable='on'; % Habilita la lampara
                app.Lamp3.Enable='off'; 
                app.DropDown.Enable='off';
         else %Ajuste Polinomial
             %app.Lamp3.Color='red';% Cambia el color de la lampara a rojo
             app.Lamp3.Enable='on';%Deshabilita la lampara
             app.Lamp2.Enable='off'; 
             app.DropDown.Enable='on';
         end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.grado=str2num(value);
        end

        % Value changed function: DatePicker_2
        function DatePicker_2ValueChanged(app, event)
            value = app.DatePicker_2.Value;
            app.date_inicial=value;
        end

        % Size changed function: AjustedelaCurvadeCOVID19Tab
        function AjustedelaCurvadeCOVID19TabSizeChanged(app, event)
            position = app.AjustedelaCurvadeCOVID19Tab.Position;
        end

        % Value changed function: DatePicker2
        function DatePicker2ValueChanged(app, event)
            value = app.DatePicker2.Value;
            app.date_final=value;
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            di=app.date_inicial;
            df=app.date_final;
            dataP=app.dataP1;
            dataP = dataP(dataP.dateRep >= di,:);
            Totalcasos = cumsum(dataP.cases);
            d=days(df-di)+1;
            d=1:d;
            d1=(di:df)';
            d2=(datetime('today'): df)';
            d2=d2([2:end],:);
            d2=[dataP.dateRep;d2];
            d3=zeros(length(d1),1);
            for i=1:length(d1)
                for j=1:length(d2)
                    if d1(i)==d2(j) ==1
                    d3(i)=1;
                    end
                    
                end
            end
            d3= logical(d3);
            d1=d1(d3);
            d=d(d3);
            length(Totalcasos);
            dias=d(1:length(Totalcasos));   
            t=char(d1);
            aprx=app.grado;
            p = polyfit(dias,Totalcasos,aprx);
            y1 = polyval(p,d);       
            a=app.ajuste;
             if a== '1'
                 %Exponencial
                xdata = dias;
                ydata = Totalcasos';
                %Create a simple exponential decay model.
                fun = @(x,xdata)x(1)*exp(x(2)*xdata);
                x0 = [1,0]; %Fit the model using the starting point x0 = [1,0].
                x = lsqcurvefit(fun,x0,xdata,ydata);
                times = linspace(d(1),d(end));
                plot(app.UIAxes6,xdata,ydata,'*:r')
                hold (app.UIAxes6,"on")
                plot(app.UIAxes6,times,fun(x,times),'b-')
                hold (app.UIAxes6,"off")
                text = [num2str(Totalcasos(end)) ' casos totales en'];
                text = strcat(text,{' '},app.myctry);
                textT = ['Aproximación Exponencial ' num2str(aprx)];
                var = {text,textT};
                legend(app.UIAxes6,var,'Location',"northwest")
                xlabel(app.UIAxes6,'fecha')
                ylabel(app.UIAxes6,'Cantidad de casos')
                title(app.UIAxes6,'Casos Covid-19 Ajuste de Curvas')
                axis(app.UIAxes6,'tight')
                xticks(app.UIAxes6,d)
                xtickangle(app.UIAxes6,90)
                xticklabels(app.UIAxes6,t)
                grid(app.UIAxes6,"on")
                hold (app.UIAxes6,"off")
                clc
                else 
                    %Polinomial  
                plot(app.UIAxes6,dias,Totalcasos,'*:r')
                hold (app.UIAxes6,"on")
                plot(app.UIAxes6,d,y1,'-b')
                text = [num2str(Totalcasos(end)) ' casos totales en'];
                text = strcat(text,{' '},app.myctry);
                textT = ['Aproximación polinomial de grado ' num2str(aprx)];
                var = {text,textT};
                legend(app.UIAxes6,var,'Location',"northwest")
                xlabel(app.UIAxes6,'fecha')
                ylabel(app.UIAxes6,'Cantidad de casos')
                title(app.UIAxes6,'Casos Covid-19 Ajuste de Curvas')
                axis(app.UIAxes6,'tight')
                xticks(app.UIAxes6,d)
                xtickangle(app.UIAxes6,90)
                xticklabels(app.UIAxes6,t)
                grid(app.UIAxes6,"on")
                hold (app.UIAxes6,"off")
             end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CargadeDatosTab = uitab(app.TabGroup);
            app.CargadeDatosTab.Tooltip = {''};
            app.CargadeDatosTab.Title = 'Carga de Datos';
            app.UITable = uitable(app.CargadeDatosTab);
            app.UITable.ColumnName = {'dateRep'; 'day'; 'month'; 'year'; 'cases'; 'deaths'; 'countriesAndTerritories'; 'geold'; 'countryterritoryCode'; 'popData2018'};
            app.UITable.RowName = {};
            app.RecopilacindeDatosButton = uibutton(app.CargadeDatosTab, 'push');
            app.RecopilacindeDatosButton.ButtonPushedFcn = createCallbackFcn(app, @RecopilacindeDatosButtonPushed, true);
            app.RecopilacindeDatosButton.Text = 'Recopilación de Datos';
            app.Label = uilabel(app.CargadeDatosTab);
            app.Label.Text = {'Nota: Los datos recopilados en esta aplicación son los datos reportados de COVID  19  de todo el mundo hasta el día actual, según la fuente de Portal de datos '; 'abiertos de la Unión Europea: '; 'European Centre For Disease Prevention And Control (2020) «Sonraí faoi choróinvíreas COVID-19». Publications Office. doi: 10.2906/101099100099/1.'};
            app.COVID19porpaisTab = uitab(app.TabGroup);
            app.COVID19porpaisTab.Title = 'COVID 19 por pais';
            app.DefinirPaisButton = uibutton(app.COVID19porpaisTab, 'push');
            app.DefinirPaisButton.ButtonPushedFcn = createCallbackFcn(app, @DefinirPaisButtonPushed, true);
            app.DefinirPaisButton.Text = 'Definir Pais';
            app.UITable2 = uitable(app.COVID19porpaisTab);
            app.UITable2.ColumnName = {'Fecha'; 'Casos'; 'Pais'};
            app.UITable2.RowName = {};
            app.UIAxes2 = uiaxes(app.COVID19porpaisTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.NotaEstosdatossonlosreportadospordaLabel = uilabel(app.COVID19porpaisTab);
            app.NotaEstosdatossonlosreportadospordaLabel.Text = {'Nota: Estos datos son los reportados por día '; 'y se encuentran representado en la grafica de barras'};
            app.Switch = uiswitch(app.COVID19porpaisTab, 'slider');
            app.Switch.Items = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            app.CasosacumuladosdelCOVID19Label = uilabel(app.COVID19porpaisTab);
            app.CasosacumuladosdelCOVID19Label.Text = 'Casos acumulados del COVID 19';
            app.DatePicker = uidatepicker(app.COVID19porpaisTab);
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.SeleccionelafechadeInicioLabel = uilabel(app.COVID19porpaisTab);
            app.SeleccionelafechadeInicioLabel.Text = 'Seleccione la fecha donde aparece el primer caso de COVID 19 en la tabla';
            app.Lamp = uilamp(app.COVID19porpaisTab);
            app.Lamp.Enable = 'off';
            app.AjustedelaCurvadeCOVID19Tab = uitab(app.TabGroup);
            app.AjustedelaCurvadeCOVID19Tab.SizeChangedFcn = createCallbackFcn(app, @AjustedelaCurvadeCOVID19TabSizeChanged, true);
            app.AjustedelaCurvadeCOVID19Tab.Title = 'Ajuste de la Curva de COVID 19';
            app.DropDown = uidropdown(app.AjustedelaCurvadeCOVID19Tab);
            app.DropDown.Items = {'Lineal', 'Cuadratico', 'Cubico', 'Polinomio de 4to grado', 'Polinomio de 5to grado', 'Polinomio de 6to grado', 'Polinomio de 7mo grado', 'Polinomio de 8vo grado'};
            app.DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = '1';
            app.SeleccioneelgradodelpolinomioLabel = uilabel(app.AjustedelaCurvadeCOVID19Tab);
            app.SeleccioneelgradodelpolinomioLabel.Text = 'Seleccione el grado del polinomio';
            app.Lamp3 = uilamp(app.AjustedelaCurvadeCOVID19Tab);
            app.Lamp3.Enable = 'off';
            app.Lamp2 = uilamp(app.AjustedelaCurvadeCOVID19Tab);
            app.Lamp2.Enable = 'off';
            app.Switch_2 = uiswitch(app.AjustedelaCurvadeCOVID19Tab, 'slider');
            app.Switch_2.Items = {'0', '1'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = '0';
            app.AjusteExponencialLabel = uilabel(app.AjustedelaCurvadeCOVID19Tab);
            app.AjusteExponencialLabel.Text = 'Ajuste Exponencial';
            app.AjustePolinomialLabel = uilabel(app.AjustedelaCurvadeCOVID19Tab);
            app.AjustePolinomialLabel.Text = 'Ajuste Polinomial';
            app.UIAxes6 = uiaxes(app.AjustedelaCurvadeCOVID19Tab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.DatePicker_2 = uidatepicker(app.AjustedelaCurvadeCOVID19Tab);
            app.DatePicker_2.ValueChangedFcn = createCallbackFcn(app, @DatePicker_2ValueChanged, true);
            app.DatePicker2 = uidatepicker(app.AjustedelaCurvadeCOVID19Tab);
            app.DatePicker2.ValueChangedFcn = createCallbackFcn(app, @DatePicker2ValueChanged, true);
            app.DefInalafechainicialLabel = uilabel(app.AjustedelaCurvadeCOVID19Tab);
            app.DefInalafechainicialLabel.Text = 'DefIna la fecha inicial';
            app.DefinalafechafinalLabel = uilabel(app.AjustedelaCurvadeCOVID19Tab);
            app.DefinalafechafinalLabel.Text = 'Defina la fecha final';
            app.GraficarButton = uibutton(app.AjustedelaCurvadeCOVID19Tab, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.ComparacionesentrePasesTab = uitab(app.TabGroup);
            app.ComparacionesentrePasesTab.Title = 'Comparaciones entre Países';
            app.TabGroup2 = uitabgroup(app.ComparacionesentrePasesTab);
            app.Pas1Tab = uitab(app.TabGroup2);
            app.Pas1Tab.Title = 'País 1';
            app.UIAxes = uiaxes(app.Pas1Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable3 = uitable(app.Pas1Tab);
            app.UITable3.ColumnName = {'Fecha'; 'Casos'};
            app.UITable3.RowName = {};
            app.PasActualLabel_2 = uilabel(app.Pas1Tab);
            app.PasActualLabel_2.Text = 'País  Actual: ';
            app.Label_2 = uilabel(app.Pas1Tab);
            app.Label_2.Text = ' ';
            app.ActualizarPas1Button = uibutton(app.Pas1Tab, 'push');
            app.ActualizarPas1Button.ButtonPushedFcn = createCallbackFcn(app, @ActualizarPas1ButtonPushed, true);
            app.ActualizarPas1Button.Text = 'Actualizar País 1';
            app.Pas2Tab = uitab(app.TabGroup2);
            app.Pas2Tab.Title = 'País 2';
            app.UIAxes4 = uiaxes(app.Pas2Tab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UITable4 = uitable(app.Pas2Tab);
            app.UITable4.ColumnName = {'Fecha'; 'Casos'};
            app.UITable4.RowName = {};
            app.PasActualLabel = uilabel(app.Pas2Tab);
            app.PasActualLabel.Text = 'País  Actual: ';
            app.Label3 = uilabel(app.Pas2Tab);
            app.Label3.Text = '  ';
            app.ActualizarPais2Button = uibutton(app.Pas2Tab, 'push');
            app.ActualizarPais2Button.ButtonPushedFcn = createCallbackFcn(app, @ActualizarPais2ButtonPushed, true);
            app.ActualizarPais2Button.Text = 'Actualizar Pais 2';
            app.ComparacinTab = uitab(app.TabGroup2);
            app.ComparacinTab.Title = 'Comparación';
            app.UIAxes5 = uiaxes(app.ComparacinTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.SeleccioneunaopcinDropDownLabel = uilabel(app.ComparacinTab);
            app.SeleccioneunaopcinDropDownLabel.Text = 'Seleccione una opción';
            app.SeleccioneunaopcinDropDown = uidropdown(app.ComparacinTab);
            app.SeleccioneunaopcinDropDown.Items = {'Casos de COVID 19 por día ', 'Curva de crecimiento '};
            app.SeleccioneunaopcinDropDown.ItemsData = {'1', '2'};
            app.SeleccioneunaopcinDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccioneunaopcinDropDownValueChanged, true);
            app.SeleccioneunaopcinDropDown.Value = '1';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'encabezado_app_matlab_covid-19.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid19

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end